classdef Interfaz_EMG < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        RegresarButton                  matlab.ui.control.Button
        BCIEMGUVGLabel                  matlab.ui.control.Label
        PaneldeControl                  matlab.ui.container.Panel
        TiemposSliderLabel              matlab.ui.control.Label
        TiemposSlider                   matlab.ui.control.Slider
        EmpezarGrabacinSwitchLabel      matlab.ui.control.Label
        EmpezarGrabacinSwitch           matlab.ui.control.Switch
        Lamp                            matlab.ui.control.Lamp
        SealesentiempoRealPanel         matlab.ui.container.Panel
        figFpz                          matlab.ui.control.UIAxes
        figFpz_2                        matlab.ui.control.UIAxes
        ClaseActualEditFieldLabel       matlab.ui.control.Label
        ClaseActualEditField            matlab.ui.control.EditField
        DesplieguedeCaractersticasentiemporealPanel  matlab.ui.container.Panel
        Canal1Label                     matlab.ui.control.Label
        EnergaPromedioEditFieldLabel    matlab.ui.control.Label
        EnergaPromedioEditField         matlab.ui.control.NumericEditField
        CoeficientedeVariacinEditFieldLabel  matlab.ui.control.Label
        CoeficientedeVariacinEditField  matlab.ui.control.NumericEditField
        KurtosisEditFieldLabel          matlab.ui.control.Label
        KurtosisEditField               matlab.ui.control.NumericEditField
        LongitudmximafractalEditFieldLabel  matlab.ui.control.Label
        LongitudmximafractalEditField   matlab.ui.control.NumericEditField
        Canal2Label                     matlab.ui.control.Label
        EnergaPromedioEditField_2Label  matlab.ui.control.Label
        EnergaPromedioEditField_2       matlab.ui.control.NumericEditField
        CoeficientedeVariacinEditField_2Label  matlab.ui.control.Label
        CoeficientedeVariacinEditField_2  matlab.ui.control.NumericEditField
        KurtosisEditField_2Label        matlab.ui.control.Label
        KurtosisEditField_2             matlab.ui.control.NumericEditField
        LongitudmximafractalEditField_2Label  matlab.ui.control.Label
        LongitudmximafractalEditField_2  matlab.ui.control.NumericEditField
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function RegresarButtonPushed(app, event)
             Interfaz_Inicio
             delete(app)
        end

        % Value changed function: EmpezarGrabacinSwitch
        function EmpezarGrabacinSwitchValueChanged(app, event)
            value = app.EmpezarGrabacinSwitch.Value;
            srate = 1000;     %Frec. muestreo
            t_inicio = 2000;  %Tiempo inicial sin mov. en ms
            t = 1;            %Tiempo total en segundos
            t_m = t*0.93;     %Tiempo mov.
            t_r = t*0.07;     %Tiempo sin mov.
            m = t*srate;      %No. muestras totales
            m_m = t_m*srate;  %No. muestras mov.
            m_r = t_r*srate;  %No. muestras sin mov.
    
            cont = 1;         %Contador no. de muestras
            cont_r = 1;       %Contador no. de muestras anteriores sin mov.
            cont_f = 0;       %Contador no. grabaciones
            grab = 10;        %No. grabaciones
    
            canales = 2;                  %No. canales
            data = zeros(canales,m_m);    %Array para almacenar datos con movimiento
            data_c = zeros(canales,m);    %Array para almacenar datos centrados 
            data_r = zeros(canales,m_r);  %Array para almacenar un tiempo sin mov.
            n_data = zeros(1,canales*m);  %Array para almacenar los dos canales unidos.
            data_f = [];                  %Array para almacenar datos filtro pasa bandas
            data_n = [];                  %Array para almacenar datos filtro notch
    
            canal_1 = zeros(1,m);                   %Almacenar todas las corridas canal 1
            canal_2 = zeros(1,m);                   %Almacenar todas las corridas canal 2
    
            F_pb = filtro_pasa_banda(srate,20,450);    %Diseñar filtro pasa banda       
            F_notch = filtro_rechaza_banda(srate);     %Diseñar filtro rechaza banda 
    
            %Inicializar variables
            v = 0; 
            v2 = zeros(1,canales);    
            volt = 0;    
            volt1 = 0;  
            ga = 0.15;                                 %Tolerancia threshold
            b_act = 0;                                 %Bandera para detectar actividad
            
               %DETALLES GR�?FICAS
            t = 1:m;
            h1 = plot(app.figFpz,t,zeros(1,m));
            ylim(app.figFpz,[0,2.5]);
            ylabel(app.figFpz,"V");
            %xlabel(handles.axes2,"ms");
            h2 = plot(app.figFpz_2,t,zeros(1,m));
            ylim(app.figFpz_2,[0,2.5]);
            ylabel(app.figFpz_2,"V");
            %xlabel(handles.axes3,"ms");
            
            %global pserial
            global stop
            stop = 0;
            puerto = 'COM3';
            delete(instrfind({'Port'},{puerto}));
            pserial=serial(puerto,'BaudRate',115200,'Timeout',10);
            fopen(pserial);
            
            
           th = identificacion2(pserial,t_inicio);    %Identificar el valor del threshold
        while stop == 0  
            
            if strcmp( value,'Off')
                            break
                        end
        for n = 1:canales
            v = fscanf(pserial,'%d');          %Leer datos puerto serial
            if(isempty(v) == 1)
                v = fscanf(pserial,'%d');
            end
            v2(:,n) = v(1)*5/1024;               %Convertir de 0-5V
             if (v2(:,n) > 2.5)
                 v2(:,n) = 0 ;
             end        
        end  
        volt = max([v2(:,1),v2(:,2)]);   %Obtener el valor máximo del voltaje entre ambos canales
        %volt  = volt1 + 1;
        %Detectar inicio de actividad
        %if volt > (th + ga) && b_act == 0 && volt < 5 && cont_r > 25
        if  b_act == 0 && volt < 5 && cont_r > 25
            %Activar bandera de inicio de actividad
            b_act = 1;
        end
        
        if b_act == 0
            data_r(1,cont_r) = v2(:,1);        %Almaceno una ventana de muestras anteriores al mov.
            data_r(2,cont_r) = v2(:,2); 
            cont_r = cont_r + 1;             
            if cont_r == m_r + 1             %Reset el contador para las muestras anteriores al mov.
                cont_r = 1;
            end
        else   
            for n_n = 1:canales
                data(n_n,cont) = v2(:,n_n);        %Guardo la señal activa
            end
            data_c = [data_r - jMeanAbsoluteValue(data_r),data - jMeanAbsoluteValue(data)];       %Centrar datos
            data_f = filter(F_pb, data_c);                          %Aplicar filtro pasa bandas
            data_n = filter(F_notch, data_f);                       %Aplicar filtro notch
            h1.YData(cont) = data_n(1,cont);                       %Actualizar gráfica
            h2.YData(cont) = data_n(2,cont); 
            drawnow limitrate
            cont = cont +1;                                            
            if(cont == (m_m+1))                                  %Evaluar longitud del contador
                cont = 1;                                        %Reiniciar contadores
                cont_r = 1;
                cont_f = cont_f + 1;                             %Aumentar contador para no. de grabaciones
                b_act = 0;                                       %Reiniciar bandera de actividad
                
                %Extracción características
                n_data = [data_n(1,:);data_n(2,:)];  %unir los 2 canales
                media = [jAverageEnergy(n_data(1,:)), jCoefficientOfVariation(n_data(1,:)), jKurtosis(data(1,:)), jMaximumFractalLength(data(1,:)),...
                         jAverageEnergy(n_data(2,:)), jCoefficientOfVariation(n_data(2,:)), jKurtosis(data(2,:)), jMaximumFractalLength(data(2,:))];

%                   app.EnergaPromedioEditField.Value = media(1,1);
% %                  app.CoeficientedeVariacinEditField.Value = media(1,2);
%                   app.KurtosisEditField.Value = media(1,3);
%                   app.LongitudmximafractalEditField.Value = media(1,4);
%                        
%                             
%                   app.EnergaPromedioEditField_2.Value = media(1,5);
%                   app.CoeficientedeVariacinEditField_2.Value = media(1,6);
%                   app.KurtosisEditField_2.Value = media(1,7);
%                   app.LongitudmximafractalEditField_2.Value = media(1,8);
                  
%                   features = media;   %Características
%                   y_prueba = net(features');
%                   clase_prueba = vec2ind(y_prueba);   % contiene la etiqueta asignada
%                   
%             switch clase_prueba
%                         case 1
%                              q1 = [0; 0;90; 0; -45; 0];
%                              R17fKine(app.R17, q1);
%                         case 2
%                               q2 = [0; 60;0; 45;90; 0];
%                              R17fKine(app.R17, q2);
%                     end 


            end
        end
    end
    
    fclose(pserial);
    delete(pserial);


        end

        % Button pushed function: RegresarButton
        function RegresarButtonPushed2(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.RegresarButton = uibutton(app.UIFigure, 'push');
            app.RegresarButton.ButtonPushedFcn = createCallbackFcn(app, @RegresarButtonPushed2, true);
            app.RegresarButton.Icon = 'back.png';
            app.RegresarButton.Text = 'Regresar';
            app.BCIEMGUVGLabel = uilabel(app.UIFigure);
            app.BCIEMGUVGLabel.Text = 'BCI EMG - UVG';
            app.PaneldeControl = uipanel(app.UIFigure);
            app.PaneldeControl.Title = 'Panel de Control';
            app.TiemposSliderLabel = uilabel(app.PaneldeControl);
            app.TiemposSliderLabel.Text = 'Tiempo (s)';
            app.TiemposSlider = uislider(app.PaneldeControl);
            app.TiemposSlider.Limits = [1 30];
            app.TiemposSlider.MajorTicks = [1 5 10 15 20 25 30];
            app.TiemposSlider.Value = 1;
            app.EmpezarGrabacinSwitchLabel = uilabel(app.PaneldeControl);
            app.EmpezarGrabacinSwitchLabel.Text = 'Empezar Grabación';
            app.EmpezarGrabacinSwitch = uiswitch(app.PaneldeControl, 'slider');
            app.EmpezarGrabacinSwitch.ValueChangedFcn = createCallbackFcn(app, @EmpezarGrabacinSwitchValueChanged, true);
            app.Lamp = uilamp(app.PaneldeControl);
            app.SealesentiempoRealPanel = uipanel(app.UIFigure);
            app.SealesentiempoRealPanel.Title = 'Señales en tiempo Real';
            app.figFpz = uiaxes(app.SealesentiempoRealPanel);
            title(app.figFpz, {'Canal 2 '; ''})
            xlabel(app.figFpz, 't (s)')
            ylabel(app.figFpz, 'uV')
            app.figFpz_2 = uiaxes(app.SealesentiempoRealPanel);
            title(app.figFpz_2, {'Canal 1 '; ''})
            xlabel(app.figFpz_2, 't (s)')
            ylabel(app.figFpz_2, 'uV')
            app.ClaseActualEditFieldLabel = uilabel(app.UIFigure);
            app.ClaseActualEditFieldLabel.Text = 'Clase Actual';
            app.ClaseActualEditField = uieditfield(app.UIFigure, 'text');
            app.DesplieguedeCaractersticasentiemporealPanel = uipanel(app.UIFigure);
            app.DesplieguedeCaractersticasentiemporealPanel.Title = 'Despliegue de Características en tiempo real ';
            app.Canal1Label = uilabel(app.DesplieguedeCaractersticasentiemporealPanel);
            app.Canal1Label.Text = 'Canal 1';
            app.EnergaPromedioEditFieldLabel = uilabel(app.DesplieguedeCaractersticasentiemporealPanel);
            app.EnergaPromedioEditFieldLabel.Text = 'Energía Promedio';
            app.EnergaPromedioEditField = uieditfield(app.DesplieguedeCaractersticasentiemporealPanel, 'numeric');
            app.CoeficientedeVariacinEditFieldLabel = uilabel(app.DesplieguedeCaractersticasentiemporealPanel);
            app.CoeficientedeVariacinEditFieldLabel.Text = 'Coeficiente de Variación';
            app.CoeficientedeVariacinEditField = uieditfield(app.DesplieguedeCaractersticasentiemporealPanel, 'numeric');
            app.KurtosisEditFieldLabel = uilabel(app.DesplieguedeCaractersticasentiemporealPanel);
            app.KurtosisEditFieldLabel.Text = 'Kurtosis';
            app.KurtosisEditField = uieditfield(app.DesplieguedeCaractersticasentiemporealPanel, 'numeric');
            app.LongitudmximafractalEditFieldLabel = uilabel(app.DesplieguedeCaractersticasentiemporealPanel);
            app.LongitudmximafractalEditFieldLabel.Text = 'Longitud máxima fractal ';
            app.LongitudmximafractalEditField = uieditfield(app.DesplieguedeCaractersticasentiemporealPanel, 'numeric');
            app.Canal2Label = uilabel(app.DesplieguedeCaractersticasentiemporealPanel);
            app.Canal2Label.Text = 'Canal 2';
            app.EnergaPromedioEditField_2Label = uilabel(app.DesplieguedeCaractersticasentiemporealPanel);
            app.EnergaPromedioEditField_2Label.Text = 'Energía Promedio';
            app.EnergaPromedioEditField_2 = uieditfield(app.DesplieguedeCaractersticasentiemporealPanel, 'numeric');
            app.CoeficientedeVariacinEditField_2Label = uilabel(app.DesplieguedeCaractersticasentiemporealPanel);
            app.CoeficientedeVariacinEditField_2Label.Text = 'Coeficiente de Variación';
            app.CoeficientedeVariacinEditField_2 = uieditfield(app.DesplieguedeCaractersticasentiemporealPanel, 'numeric');
            app.KurtosisEditField_2Label = uilabel(app.DesplieguedeCaractersticasentiemporealPanel);
            app.KurtosisEditField_2Label.Text = 'Kurtosis';
            app.KurtosisEditField_2 = uieditfield(app.DesplieguedeCaractersticasentiemporealPanel, 'numeric');
            app.LongitudmximafractalEditField_2Label = uilabel(app.DesplieguedeCaractersticasentiemporealPanel);
            app.LongitudmximafractalEditField_2Label.Text = 'Longitud máxima fractal ';
            app.LongitudmximafractalEditField_2 = uieditfield(app.DesplieguedeCaractersticasentiemporealPanel, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Interfaz_EMG

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end